classdef eac_individualized_hrtf < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        MainTab                        matlab.ui.container.Tab
        HELPERMANUALPHOTOANTHROPOMETRYButton  matlab.ui.control.Button
        InputleftearonlyCheckBox       matlab.ui.control.CheckBox
        Importdimensionsfromtable      matlab.ui.control.CheckBox
        excel_name                     matlab.ui.control.EditField
        LoadButton                     matlab.ui.control.Button
        StartButton                    matlab.ui.control.StateButton
        GeneralOptionsPanel            matlab.ui.container.Panel
        SavePathButton                 matlab.ui.control.Button
        UnitsDropDown                  matlab.ui.control.DropDown
        UnitsLabel                     matlab.ui.control.Label
        ExportHeSuViCheckBox           matlab.ui.control.CheckBox
        ExportSOFACheckBox             matlab.ui.control.CheckBox
        SampleRate                     matlab.ui.control.NumericEditField
        SampleRateEditFieldLabel       matlab.ui.control.Label
        FileName                       matlab.ui.control.EditField
        RightearPanel                  matlab.ui.container.Panel
        d8R                            matlab.ui.control.NumericEditField
        d8EditFieldLabel_4             matlab.ui.control.Label
        d7R                            matlab.ui.control.NumericEditField
        d7EditFieldLabel_4             matlab.ui.control.Label
        d5R                            matlab.ui.control.NumericEditField
        d5Label_2                      matlab.ui.control.Label
        d3R                            matlab.ui.control.NumericEditField
        d3Label_2                      matlab.ui.control.Label
        d2R                            matlab.ui.control.NumericEditField
        d2Label_2                      matlab.ui.control.Label
        d1R                            matlab.ui.control.NumericEditField
        d1EditFieldLabel_4             matlab.ui.control.Label
        LeftearPanel                   matlab.ui.container.Panel
        d8L                            matlab.ui.control.NumericEditField
        d8EditFieldLabel_3             matlab.ui.control.Label
        d7L                            matlab.ui.control.NumericEditField
        d7EditFieldLabel_3             matlab.ui.control.Label
        d5L                            matlab.ui.control.NumericEditField
        d5Label                        matlab.ui.control.Label
        d3L                            matlab.ui.control.NumericEditField
        d3Label                        matlab.ui.control.Label
        d2L                            matlab.ui.control.NumericEditField
        d2Label                        matlab.ui.control.Label
        d1L                            matlab.ui.control.NumericEditField
        d1EditFieldLabel_3             matlab.ui.control.Label
        HeadPanel                      matlab.ui.container.Panel
        x3                             matlab.ui.control.NumericEditField
        x3EditFieldLabel               matlab.ui.control.Label
        x1                             matlab.ui.control.NumericEditField
        x1EditFieldLabel               matlab.ui.control.Label
        Image3                         matlab.ui.control.Image
        EACIndividualizedHRTFLabel     matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        HeSuVioptionsTab               matlab.ui.container.Tab
        AzimElevDropDown_7             matlab.ui.control.DropDown
        AzimElevDropDown_7Label        matlab.ui.control.Label
        AzimElevDropDown_6             matlab.ui.control.DropDown
        AzimElevDropDown_6Label        matlab.ui.control.Label
        AzimElevDropDown_5             matlab.ui.control.DropDown
        AzimElevDropDown_5Label        matlab.ui.control.Label
        AzimElevDropDown_4             matlab.ui.control.DropDown
        AzimElevDropDown_4Label        matlab.ui.control.Label
        AzimElevDropDown_3             matlab.ui.control.DropDown
        AzimElevDropDown_3Label        matlab.ui.control.Label
        AzimElevDropDown_2             matlab.ui.control.DropDown
        AzimElevDropDown_2Label        matlab.ui.control.Label
        AzimElevDropDown               matlab.ui.control.DropDown
        AzimElevDropDownLabel          matlab.ui.control.Label
        Image4                         matlab.ui.control.Image
        AdvancedTab                    matlab.ui.container.Tab
        TextArea                       matlab.ui.control.TextArea
        ResolutionAzimuthdegEditField  matlab.ui.control.NumericEditField
        ResolutionAzimuthdegEditFieldLabel  matlab.ui.control.Label
        MaxAzimuthEditField            matlab.ui.control.NumericEditField
        MaxAzimuthEditFieldLabel       matlab.ui.control.Label
        MinAzimuthEditField            matlab.ui.control.NumericEditField
        MinAzimuthEditFieldLabel       matlab.ui.control.Label
        ResolutionElevationdegEditField  matlab.ui.control.NumericEditField
        ResolutionElevationdegEditFieldLabel  matlab.ui.control.Label
        MaxElevationEditField          matlab.ui.control.NumericEditField
        MaxElevationEditFieldLabel     matlab.ui.control.Label
        MinElevationEditField          matlab.ui.control.NumericEditField
        MinElevationEditFieldLabel     matlab.ui.control.Label
        CustomGridCheckBox             matlab.ui.control.CheckBox
        AboutTab                       matlab.ui.container.Tab
        httpsgithubcomdavircarvalhoIndividualized_HRTF_SynthesisLabel  matlab.ui.control.Label
        ContactdavicarvalhoeacufsmbrLabel  matlab.ui.control.Label
        EACIndividualizedHRTFEngenhariaAcsticaUFSM2022Label  matlab.ui.control.Label
    end

    
    properties (Access = public)
        tab_array     %array contendo dimensões importadas (Excel)   
        output_path   % save path 
        image_path
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure,"center")
%             pause(0)
            app.tab_array = [];            
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            %% Trocar mensagem do botão
            app.StartButton.Enable = 0; 
            app.StartButton.Text = 'Working...';
                                
            
            % Verify if saving path was defined
            if app.output_path                
                % Verify if input is 'left side only' 
                if app.InputleftearonlyCheckBox.Value
                    InputleftearonlyCheckBoxValueChanged(app, event)
                end
                
                % Verify if dimensions were imported
                if app.Importdimensionsfromtable.Value && isempty(app.tab_array)            
                    warndlg('Table dimensions not found!', 'Error')
                    app.StartButton.Enable = 1;
                    app.StartButton.Text = 'Start';               
                end
                
                if app.Importdimensionsfromtable.Value && ~isempty(app.tab_array) || ...
                   ~app.Importdimensionsfromtable.Value    
             
                    % Units (make sure engine input is cm) 
                    switch app.UnitsDropDown.Value 
                        case 'in'
                            %convert engine input to centimeters
                            u = symunit;
                            inch2cm = double(unitConversionFactor(u.inch,u.cm));
                            
                            if app.Importdimensionsfromtable.Value
                                app.tab_array = inch2cm.*app.tab_array;
                            else
                                % set left values 
                                app.d1L.Value = inch2cm*app.d1L.Value;
                                app.d2L.Value = inch2cm*app.d2L.Value;
                                app.d3L.Value = inch2cm*app.d3L.Value;
                                app.d5L.Value = inch2cm*app.d5L.Value;
                                app.d7L.Value = inch2cm*app.d7L.Value;
                                app.d8L.Value = inch2cm*app.d8L.Value; 
                                 % set right values 
                                app.d1R.Value = inch2cm*app.d1R.Value;
                                app.d2R.Value = inch2cm*app.d2R.Value;
                                app.d3R.Value = inch2cm*app.d3R.Value;
                                app.d5R.Value = inch2cm*app.d5R.Value;
                                app.d7R.Value = inch2cm*app.d7R.Value;
                                app.d8R.Value = inch2cm*app.d8R.Value;                                       
                            end
                     end
                    
                    % -- Handle custom grid 
                    if app.CustomGridCheckBox.Value
                        out_pos = equiangular_coordinates(app.MinElevationEditField.Value,...
                                                          app.MaxElevationEditField.Value,...
                                                          app.MinAzimuthEditField.Value,...
                                                          app.MaxAzimuthEditField.Value,...
                                                          app.ResolutionElevationdegEditField.Value,...
                                                          app.ResolutionAzimuthdegEditField.Value);
                        disp('Dont worry, I got you')
                    else
                        out_pos = false;
                    end
                    
                    %%%% Run Engine %%%%
                    if ~isempty(app.tab_array) % Use table input 
                        dL = app.tab_array(:, 3:8);
                        dR = app.tab_array(:, 9:14);
                        x  = app.tab_array(:, 1:2); 
                        for k = 1:size(x, 1)
                            model_engine(x(k,1), x(k,2),...
                            dL(k,1), dL(k,2), dL(k,3), ...
                            dL(k,4), dL(k,5), dL(k,6), ...
                            ...
                            dR(k,1), dR(k,2), dR(k,3), ...
                            dR(k,4), dR(k,5), dR(k,6), ...
                            ...
                            [app.output_path, app.FileName.Value, '_', num2str(k)], ...
                            app.ExportHeSuViCheckBox.Value,...
                            app.ExportSOFACheckBox.Value, ...
                            app.SampleRate.Value,...
                            ...
                            app.AzimElevDropDown.Value,...    
                            app.AzimElevDropDown_2.Value,... 
                            app.AzimElevDropDown_3.Value,... 
                            app.AzimElevDropDown_7.Value,...
                            app.AzimElevDropDown_6.Value,... 
                            app.AzimElevDropDown_5.Value,...
                            app.AzimElevDropDown_4.Value,...
                            out_pos)            
                        end
                        
                    else % Use field input
                        model_engine(app.x1.Value, app.x3.Value,...
                        app.d1L.Value, app.d2L.Value, app.d3L.Value, ...
                        app.d5L.Value, app.d7L.Value, app.d8L.Value, ...
                        ...
                        app.d1R.Value, app.d2R.Value, app.d3R.Value, ...
                        app.d5R.Value, app.d7R.Value, app.d8R.Value, ...
                        ...
                        [app.output_path, app.FileName.Value], ...
                        app.ExportHeSuViCheckBox.Value,...
                        app.ExportSOFACheckBox.Value, ...
                        app.SampleRate.Value,...
                        ...
                        app.AzimElevDropDown.Value,...    
                        app.AzimElevDropDown_2.Value,... 
                        app.AzimElevDropDown_3.Value,... 
                        app.AzimElevDropDown_7.Value,...
                        app.AzimElevDropDown_6.Value,... 
                        app.AzimElevDropDown_5.Value,...
                        app.AzimElevDropDown_4.Value, ...
                        out_pos)                
                    end                   
                end
            end
            % reset buttom
            app.StartButton.Text = 'Start';
            app.StartButton.Enable = 1;
            
        end

        % Value changed function: InputleftearonlyCheckBox
        function InputleftearonlyCheckBoxValueChanged(app, event)
            if app.InputleftearonlyCheckBox.Value % use left side only 
                % disable right inputs
                app.d1R.Enable = 0;
                app.d2R.Enable = 0;
                app.d3R.Enable = 0;
                app.d5R.Enable = 0;
                app.d7R.Enable = 0;
                app.d8R.Enable = 0;
                % set left values to right variables
                if app.Importdimensionsfromtable.Value == 0
                    app.d1R.Value = app.d1L.Value;
                    app.d2R.Value = app.d2L.Value;
                    app.d3R.Value = app.d3L.Value;
                    app.d5R.Value = app.d5L.Value;
                    app.d7R.Value = app.d7L.Value;
                    app.d8R.Value = app.d8L.Value;  
                end
            else % use both sides 
                % enable right inputs
                if app.Importdimensionsfromtable.Value == 0
                    app.d1R.Enable = 1;
                    app.d2R.Enable = 1;
                    app.d3R.Enable = 1;
                    app.d5R.Enable = 1;
                    app.d7R.Enable = 1;
                    app.d8R.Enable = 1;
                end
            end
        end

        % Value changed function: Importdimensionsfromtable
        function ImportdimensionsfromtableValueChanged(app, event)
            value = app.Importdimensionsfromtable.Value;
            app.LoadButton.Enable = value; 
            if value % disable manual inputs
                app.d1L.Enable = 0;
                app.d2L.Enable = 0;
                app.d3L.Enable = 0;
                app.d5L.Enable = 0;
                app.d7L.Enable = 0;
                app.d8L.Enable = 0;
                app.d1R.Enable = 0;
                app.d2R.Enable = 0;
                app.d3R.Enable = 0;
                app.d5R.Enable = 0;
                app.d7R.Enable = 0;
                app.d8R.Enable = 0; 
                app.x1.Enable = 0;
                app.x3.Enable = 0;
            end
            if value == 0 % enable manual inputs               
                app.d1L.Enable = 1;
                app.d2L.Enable = 1;
                app.d3L.Enable = 1;
                app.d5L.Enable = 1;
                app.d7L.Enable = 1;
                app.d8L.Enable = 1;
                app.d1R.Enable = 1;
                app.d2R.Enable = 1;
                app.d3R.Enable = 1;
                app.d5R.Enable = 1;
                app.d7R.Enable = 1;
                app.d8R.Enable = 1;
                app.x1.Enable = 1;
                app.x3.Enable = 1;
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            accepted_ext = ('*.xls; *.xlsb; *.xlsm; *.xlsx; *.xltm;*.xltx; *.ods; *.txt; *.dat; *.csv');
            [baseName, folder] = uigetfile(accepted_ext, 'Load table:');
            app.excel_name.Value = baseName;  
            figure(app.UIFigure) %keep focus on app window
            
            % LOAD excel table
            temp_table = readtable(fullfile(folder, baseName));
            tab = table;          
            tab.x1 = temp_table.x1;
            tab.x3 = temp_table.x3;            
            tab.L_d1 = temp_table.L_d1;
            tab.L_d2 = temp_table.L_d2;
            tab.L_d3 = temp_table.L_d3;
            tab.L_d5 = temp_table.L_d5;
            tab.L_d7 = temp_table.L_d7;
            tab.L_d8 = temp_table.L_d8;     
            
            if app.InputleftearonlyCheckBox.Value
                tab.R_d1 = temp_table.L_d1;
                tab.R_d2 = temp_table.L_d2;
                tab.R_d3 = temp_table.L_d3;
                tab.R_d5 = temp_table.L_d5;
                tab.R_d7 = temp_table.L_d7;
                tab.R_d8 = temp_table.L_d8;      
            else
                tab.R_d1 = temp_table.R_d1;
                tab.R_d2 = temp_table.R_d2;
                tab.R_d3 = temp_table.R_d3;
                tab.R_d5 = temp_table.R_d5;
                tab.R_d7 = temp_table.R_d7;
                tab.R_d8 = temp_table.R_d8;        
            end  
            
            app.tab_array = table2array(tab);          
            
            % Trocar separador decimal para ponto caso necessario 
            if iscell(app.tab_array)
                app.tab_array = str2double(regexprep(app.tab_array, ',', '.'));
            end
            
            % Verificar se tem algum NaN na tabela
            if any(isnan(app.tab_array(:)))
                 warndlg('Incomplete table: remove or complete NaN values', 'Error');
            end        
        end

        % Button pushed function: SavePathButton
        function SavePathButtonPushed(app, event)
            [file,app.output_path] = uiputfile('*.*');
            figure(app.UIFigure)  %keep focus on app window
            app.FileName.Value = file;
            app.StartButton.Enable = 1;
        end

        % Button pushed function: HELPERMANUALPHOTOANTHROPOMETRYButton
        function HELPERMANUALPHOTOANTHROPOMETRYButtonPushed(app, event)
            msgbox({'1# - Load an ear picture with a known size object by the side';
                    '2# - Click and drag to measure the object distance in the picture';
                    '3# - Double click on the segment to convert pixel to milimeters'
                    '4# - Measure the required ear dimensions'}, 'TUTORIAL')
            pause(2)
            [file,app.image_path] = uigetfile({'*.jpg;*.png;*.jpeg;*.hdf;*.bmp;*.pnn;*.tif;*.tiff;*.xwd'});
            
            figure(app.UIFigure)  %keep focus on app window
            photo_helper([app.image_path, file])
            app.FileName.Value = file;

        end

        % Value changed function: CustomGridCheckBox
        function CustomGridCheckBoxValueChanged(app, event)
            value = app.CustomGridCheckBox.Value;
            if value
                app.MaxElevationEditField.Enable = true;
                app.MinAzimuthEditField.Enable = true;
                app.MinElevationEditField.Enable = true;
                app.MaxAzimuthEditField.Enable = true;
                app.ResolutionAzimuthdegEditField.Enable = true;
                app.ResolutionElevationdegEditField.Enable = true;
            else
                app.MaxElevationEditField.Enable = false;
                app.MinAzimuthEditField.Enable = false;
                app.MinElevationEditField.Enable = false;
                app.MaxAzimuthEditField.Enable = false;
                app.ResolutionAzimuthdegEditField.Enable = false;
                app.ResolutionElevationdegEditField.Enable = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.Image = uiimage(app.MainTab);
            app.Image.ImageSource = 'anthro1.PNG';
            app.Image2 = uiimage(app.MainTab);
            app.Image2.ImageSource = 'anthro2.PNG';
            app.EACIndividualizedHRTFLabel = uilabel(app.MainTab);
            app.EACIndividualizedHRTFLabel.Text = 'EAC Individualized HRTF';
            app.Image3 = uiimage(app.MainTab);
            app.Image3.ImageSource = 'anthro3.PNG';
            app.HeadPanel = uipanel(app.MainTab);
            app.HeadPanel.Title = 'Head';
            app.x1EditFieldLabel = uilabel(app.HeadPanel);
            app.x1EditFieldLabel.Text = 'x1';
            app.x1 = uieditfield(app.HeadPanel, 'numeric');
            app.x1.Value = 16.3;
            app.x3EditFieldLabel = uilabel(app.HeadPanel);
            app.x3EditFieldLabel.Text = 'x3';
            app.x3 = uieditfield(app.HeadPanel, 'numeric');
            app.x3.Value = 19.5;
            app.LeftearPanel = uipanel(app.MainTab);
            app.LeftearPanel.Title = 'Left ear';
            app.d1EditFieldLabel_3 = uilabel(app.LeftearPanel);
            app.d1EditFieldLabel_3.Text = 'd1';
            app.d1L = uieditfield(app.LeftearPanel, 'numeric');
            app.d1L.Value = 1.98;
            app.d2Label = uilabel(app.LeftearPanel);
            app.d2Label.Text = 'd2';
            app.d2L = uieditfield(app.LeftearPanel, 'numeric');
            app.d2L.Value = 0.84;
            app.d3Label = uilabel(app.LeftearPanel);
            app.d3Label.Text = 'd3';
            app.d3L = uieditfield(app.LeftearPanel, 'numeric');
            app.d3L.Value = 1.75;
            app.d5Label = uilabel(app.LeftearPanel);
            app.d5Label.Text = 'd5';
            app.d5L = uieditfield(app.LeftearPanel, 'numeric');
            app.d5L.Value = 7.42;
            app.d7EditFieldLabel_3 = uilabel(app.LeftearPanel);
            app.d7EditFieldLabel_3.Text = 'd7';
            app.d7L = uieditfield(app.LeftearPanel, 'numeric');
            app.d7L.Value = 0.68;
            app.d8EditFieldLabel_3 = uilabel(app.LeftearPanel);
            app.d8EditFieldLabel_3.Text = 'd8';
            app.d8L = uieditfield(app.LeftearPanel, 'numeric');
            app.d8L.Value = 1.2;
            app.RightearPanel = uipanel(app.MainTab);
            app.RightearPanel.Title = 'Right ear';
            app.d1EditFieldLabel_4 = uilabel(app.RightearPanel);
            app.d1EditFieldLabel_4.Text = 'd1';
            app.d1R = uieditfield(app.RightearPanel, 'numeric');
            app.d2Label_2 = uilabel(app.RightearPanel);
            app.d2Label_2.Text = 'd2';
            app.d2R = uieditfield(app.RightearPanel, 'numeric');
            app.d3Label_2 = uilabel(app.RightearPanel);
            app.d3Label_2.Text = 'd3';
            app.d3R = uieditfield(app.RightearPanel, 'numeric');
            app.d5Label_2 = uilabel(app.RightearPanel);
            app.d5Label_2.Text = 'd5';
            app.d5R = uieditfield(app.RightearPanel, 'numeric');
            app.d7EditFieldLabel_4 = uilabel(app.RightearPanel);
            app.d7EditFieldLabel_4.Text = 'd7';
            app.d7R = uieditfield(app.RightearPanel, 'numeric');
            app.d8EditFieldLabel_4 = uilabel(app.RightearPanel);
            app.d8EditFieldLabel_4.Text = 'd8';
            app.d8R = uieditfield(app.RightearPanel, 'numeric');
            app.GeneralOptionsPanel = uipanel(app.MainTab);
            app.GeneralOptionsPanel.Title = 'General Options';
            app.FileName = uieditfield(app.GeneralOptionsPanel, 'text');
            app.FileName.Editable = 'off';
            app.FileName.Enable = 'off';
            app.SampleRateEditFieldLabel = uilabel(app.GeneralOptionsPanel);
            app.SampleRateEditFieldLabel.Text = 'Sample Rate';
            app.SampleRate = uieditfield(app.GeneralOptionsPanel, 'numeric');
            app.SampleRate.Value = 44100;
            app.ExportSOFACheckBox = uicheckbox(app.GeneralOptionsPanel);
            app.ExportSOFACheckBox.Text = 'Export SOFA';
            app.ExportSOFACheckBox.Value = true;
            app.ExportHeSuViCheckBox = uicheckbox(app.GeneralOptionsPanel);
            app.ExportHeSuViCheckBox.Text = 'Export HeSuVi';
            app.UnitsLabel = uilabel(app.GeneralOptionsPanel);
            app.UnitsLabel.Text = 'Units:';
            app.UnitsDropDown = uidropdown(app.GeneralOptionsPanel);
            app.UnitsDropDown.Items = {'cm', 'in', ''};
            app.UnitsDropDown.Value = 'cm';
            app.SavePathButton = uibutton(app.GeneralOptionsPanel, 'push');
            app.SavePathButton.ButtonPushedFcn = createCallbackFcn(app, @SavePathButtonPushed, true);
            app.SavePathButton.Text = 'Save Path';
            app.StartButton = uibutton(app.MainTab, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.LoadButton = uibutton(app.MainTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Enable = 'off';
            app.LoadButton.Text = 'Load';
            app.excel_name = uieditfield(app.MainTab, 'text');
            app.excel_name.Editable = 'off';
            app.excel_name.Enable = 'off';
            app.Importdimensionsfromtable = uicheckbox(app.MainTab);
            app.Importdimensionsfromtable.ValueChangedFcn = createCallbackFcn(app, @ImportdimensionsfromtableValueChanged, true);
            app.Importdimensionsfromtable.Text = 'Import dimensions from table';
            app.InputleftearonlyCheckBox = uicheckbox(app.MainTab);
            app.InputleftearonlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @InputleftearonlyCheckBoxValueChanged, true);
            app.InputleftearonlyCheckBox.Text = 'Input left ear only';
            app.InputleftearonlyCheckBox.Value = true;
            app.HELPERMANUALPHOTOANTHROPOMETRYButton = uibutton(app.MainTab, 'push');
            app.HELPERMANUALPHOTOANTHROPOMETRYButton.ButtonPushedFcn = createCallbackFcn(app, @HELPERMANUALPHOTOANTHROPOMETRYButtonPushed, true);
            app.HELPERMANUALPHOTOANTHROPOMETRYButton.Text = {'[HELPER] MANUAL '; 'PHOTO-ANTHROPOMETRY'};
            app.HeSuVioptionsTab = uitab(app.TabGroup);
            app.HeSuVioptionsTab.Title = 'HeSuVi options';
            app.Image4 = uiimage(app.HeSuVioptionsTab);
            app.Image4.ImageSource = 'HeSuVi_posi.png';
            app.AzimElevDropDownLabel = uilabel(app.HeSuVioptionsTab);
            app.AzimElevDropDownLabel.Text = 'Azim, Elev';
            app.AzimElevDropDown = uidropdown(app.HeSuVioptionsTab);
            app.AzimElevDropDown.Items = {'30°, -30°', '45°, -30°', '50°, -30°', '30°, -15°', '45°, -15°', '50°, -15°', '20°, 0°', '30°, 0°', '35°, 0°', '40°, 0°', '45°, 0°', '50°, 0°', '30°, 15°', '45°, 15°', '50°, 15°', '30°, 30°', '45°, 30°', '50°, 30°', '', ''};
            app.AzimElevDropDown.Value = '35°, 0°';
            app.AzimElevDropDown_2Label = uilabel(app.HeSuVioptionsTab);
            app.AzimElevDropDown_2Label.Text = 'Azim, Elev';
            app.AzimElevDropDown_2 = uidropdown(app.HeSuVioptionsTab);
            app.AzimElevDropDown_2.Items = {'80°, -30°', '90°, -30°', '110°, -30°', '80°, -15°', '90°, -15°', '110°, -15°', '80°, 0°', '90°, 0°', '110°, 0°', '80°, 15°', '90°, 15°', '110°, 15°', '80°, 30°', '90°, 30°', '110°, 30°', '', ''};
            app.AzimElevDropDown_2.Value = '110°, 0°';
            app.AzimElevDropDown_3Label = uilabel(app.HeSuVioptionsTab);
            app.AzimElevDropDown_3Label.Text = 'Azim, Elev';
            app.AzimElevDropDown_3 = uidropdown(app.HeSuVioptionsTab);
            app.AzimElevDropDown_3.Items = {'130°, -30°', '140°, -30°', '150°, -30°', '130°, -15°', '140°, -15°', '150°, -15°', '130°, 0°', '140°, 0°', '150°, 0°', '160°, 0°', '130°, 15°', '140°, 15°', '150°, 15°', '130°, 30°', '140°, 30°', '150°, 30°', '', ''};
            app.AzimElevDropDown_3.Value = '150°, 0°';
            app.AzimElevDropDown_4Label = uilabel(app.HeSuVioptionsTab);
            app.AzimElevDropDown_4Label.Text = 'Azim, Elev';
            app.AzimElevDropDown_4 = uidropdown(app.HeSuVioptionsTab);
            app.AzimElevDropDown_4.Items = {'130°, -30°', '140°, -30°', '150°, -30°', '130°, -15°', '140°, -15°', '150°, -15°', '130°, 0°', '140°, 0°', '150°, 0°', '160°, 0°', '130°, 15°', '140°, 15°', '150°, 15°', '130°, 30°', '140°, 30°', '150°, 30°'};
            app.AzimElevDropDown_4.Value = '150°, 0°';
            app.AzimElevDropDown_5Label = uilabel(app.HeSuVioptionsTab);
            app.AzimElevDropDown_5Label.Text = 'Azim, Elev';
            app.AzimElevDropDown_5 = uidropdown(app.HeSuVioptionsTab);
            app.AzimElevDropDown_5.Items = {'80°, -30°', '90°, -30°', '110°, -30°', '80°, -15°', '90°, -15°', '110°, -15°', '80°, 0°', '90°, 0°', '110°, 0°', '80°, 15°', '90°, 15°', '110°, 15°', '80°, 30°', '90°, 30°', '110°, 30°', '', ''};
            app.AzimElevDropDown_5.Value = '110°, 0°';
            app.AzimElevDropDown_6Label = uilabel(app.HeSuVioptionsTab);
            app.AzimElevDropDown_6Label.Text = 'Azim, Elev';
            app.AzimElevDropDown_6 = uidropdown(app.HeSuVioptionsTab);
            app.AzimElevDropDown_6.Items = {'30°, -30°', '45°, -30°', '50°, -30°', '30°, -15°', '45°, -15°', '50°, -15°', '20°, 0°', '30°, 0°', '35°, 0°', '40°, 0°', '45°, 0°', '50°, 0°', '30°, 15°', '45°, 15°', '50°, 15°', '30°, 30°', '45°, 30°', '50°, 30°'};
            app.AzimElevDropDown_6.Value = '35°, 0°';
            app.AzimElevDropDown_7Label = uilabel(app.HeSuVioptionsTab);
            app.AzimElevDropDown_7Label.Text = 'Azim, Elev';
            app.AzimElevDropDown_7 = uidropdown(app.HeSuVioptionsTab);
            app.AzimElevDropDown_7.Items = {'0°, -30°', '0°, -10°', '0°, 0°', '0°, 15°', '0°, 30°', ''};
            app.AzimElevDropDown_7.Value = '0°, 0°';
            app.AdvancedTab = uitab(app.TabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.CustomGridCheckBox = uicheckbox(app.AdvancedTab);
            app.CustomGridCheckBox.ValueChangedFcn = createCallbackFcn(app, @CustomGridCheckBoxValueChanged, true);
            app.CustomGridCheckBox.Text = 'Custom Grid';
            app.MinElevationEditFieldLabel = uilabel(app.AdvancedTab);
            app.MinElevationEditFieldLabel.Text = 'Min Elevation';
            app.MinElevationEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.MinElevationEditField.Enable = 'off';
            app.MinElevationEditField.Value = -90;
            app.MaxElevationEditFieldLabel = uilabel(app.AdvancedTab);
            app.MaxElevationEditFieldLabel.Text = 'Max Elevation';
            app.MaxElevationEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.MaxElevationEditField.Enable = 'off';
            app.MaxElevationEditField.Value = 90;
            app.ResolutionElevationdegEditFieldLabel = uilabel(app.AdvancedTab);
            app.ResolutionElevationdegEditFieldLabel.Text = 'Resolution Elevation (deg)';
            app.ResolutionElevationdegEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.ResolutionElevationdegEditField.Enable = 'off';
            app.ResolutionElevationdegEditField.Value = 5;
            app.MinAzimuthEditFieldLabel = uilabel(app.AdvancedTab);
            app.MinAzimuthEditFieldLabel.Text = 'Min Azimuth';
            app.MinAzimuthEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.MinAzimuthEditField.Enable = 'off';
            app.MaxAzimuthEditFieldLabel = uilabel(app.AdvancedTab);
            app.MaxAzimuthEditFieldLabel.Text = 'Max Azimuth';
            app.MaxAzimuthEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.MaxAzimuthEditField.Enable = 'off';
            app.MaxAzimuthEditField.Value = 360;
            app.ResolutionAzimuthdegEditFieldLabel = uilabel(app.AdvancedTab);
            app.ResolutionAzimuthdegEditFieldLabel.Text = 'Resolution Azimuth (deg)';
            app.ResolutionAzimuthdegEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.ResolutionAzimuthdegEditField.Enable = 'off';
            app.ResolutionAzimuthdegEditField.Value = 5;
            app.TextArea = uitextarea(app.AdvancedTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Interpolation method is a hybrid between bilinear and nearest neighbor, where nearest neighbor is used only for elevations beyond the original grid (x > -55) and (x < 55)'};
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.EACIndividualizedHRTFEngenhariaAcsticaUFSM2022Label = uilabel(app.AboutTab);
            app.EACIndividualizedHRTFEngenhariaAcsticaUFSM2022Label.Text = 'EAC Individualized HRTF - Engenharia Acústica UFSM -  2022';
            app.ContactdavicarvalhoeacufsmbrLabel = uilabel(app.AboutTab);
            app.ContactdavicarvalhoeacufsmbrLabel.Text = 'Contact: davi.carvalho@eac.ufsm.br';
            app.httpsgithubcomdavircarvalhoIndividualized_HRTF_SynthesisLabel = uilabel(app.AboutTab);
            app.httpsgithubcomdavircarvalhoIndividualized_HRTF_SynthesisLabel.Text = 'https://github.com/davircarvalho/Individualized_HRTF_Synthesis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eac_individualized_hrtf

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end